classdef GUI_Handler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        StaticTab            matlab.ui.container.Tab
        EnterCellIDsseparatedbyEditFieldLabel  matlab.ui.control.Label
        CellID               matlab.ui.control.EditField
        EntertestedtemperaturesseparatedbyEditFieldLabel  matlab.ui.control.Label
        temp                 matlab.ui.control.EditField
        EnterminVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        minV                 matlab.ui.control.EditField
        EntermaxVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        maxV                 matlab.ui.control.EditField
        Simulate             matlab.ui.control.Button
        LogsTextAreaLabel    matlab.ui.control.Label
        Logs                 matlab.ui.control.TextArea
        Save                 matlab.ui.control.CheckBox
        DynamicTab           matlab.ui.container.Tab
        LogsTextAreaLabel_2  matlab.ui.control.Label
        Logs_2               matlab.ui.control.TextArea
        EVSimTab             matlab.ui.container.Tab
        LogsTextAreaLabel_3  matlab.ui.control.Label
        Logs_3               matlab.ui.control.TextArea
        CellSimTab           matlab.ui.container.Tab
        LogsTextAreaLabel_4  matlab.ui.control.Label
        Logs_4               matlab.ui.control.TextArea
        AboutTab             matlab.ui.container.Tab
        AbouttheappLabel     matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        TextArea_2           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        iTextBox = 0;
    end
    
    methods (Access = public)
        
        function DisplayLog(app,message)
        app.iTextBox = app.iTextBox + 1;
        app.Logs.Value = [app.Logs.Value;message];
       
        end
    end
    
    methods (Access = private)
        
        function results = simulate(~,cellIDs,temps,minV,maxV,saveornot)
            addpath('ESCToolbox/OCV_Files')
            addpath('ESCToolbox/DYN_Files/')
            addpath('EVsim/')
            addpath('PCMSCM/')
            result = RunProcessOCVGUI(cellIDs,temps,minV,maxV,saveornot);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
  
            a = app.CellID.Value;
            b=erase(a,{'[',']'});
            cellIDs=split(b,{' ',','});
            
            a = app.temp.Value;
            temps=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});            
            %temps=(split(b,[' ',',']));
            
            
            a = app.minV.Value;
            minVs=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});
            %minVs=(split(b,{' ',','}));
            
            
            a = app.maxV.Value;
            maxVs=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});
            %maxVs=(split(b,{' ',','}));
      
            a=0;
            
            saveornot = app.Save.Value;
            
            log = sprintf('you have entered cell IDs as');
            DisplayLog(app,log)
            log = sprintf('  %s ',cellIDs{:});
            DisplayLog(app,log)
            
            log = sprintf('you have entered temp values as');
            DisplayLog(app,log)
            log = sprintf('  %d ',temps());
            DisplayLog(app,log)
            
            log = sprintf('you have entered min voltages as');
            DisplayLog(app,log)
            log = sprintf('  %d ',minVs());
            DisplayLog(app,log)
            
            log = sprintf('you have entered max voltages as');
            DisplayLog(app,log)
            log = sprintf('  %d ',maxVs());
            DisplayLog(app,log)


            if( max(size(cellIDs)) ~= max(size(minVs))  || max(size(maxVs)) ~= max(size(minVs)) )
                log = sprintf('you have entered incorrect number of data( no. of Cell IDs , Min Voltages and Max Voltages should be same)');
                DisplayLog(app,log)
            else
                %%simulate
                %minVs = str2double(minVs);
                %minVs = str2double(minVs);
                %temps= str2double(temps);
                ftemps = {};
                for i = 1:length(cellIDs)
                    ftemps{i} = temps;
                end
                
                simulate(app,cellIDs,ftemps,minVs,maxVs,saveornot)
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.EnterCellIDsseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EnterCellIDsseparatedbyEditFieldLabel.Text = 'Enter Cell IDs separated by '','' ';
            app.CellID = uieditfield(app.StaticTab, 'text');
            app.CellID.Value = 'A123,SAM';
            app.EntertestedtemperaturesseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EntertestedtemperaturesseparatedbyEditFieldLabel.Text = 'Enter tested temperatures separated by '',''';
            app.temp = uieditfield(app.StaticTab, 'text');
            app.temp.Value = '25,35,-25';
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter min Voltages for each cell separated by '',''';
            app.minV = uieditfield(app.StaticTab, 'text');
            app.minV.Value = '2,2.5';
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter max Voltages for each cell separated by '',''';
            app.maxV = uieditfield(app.StaticTab, 'text');
            app.maxV.Value = '3.75,4.25';
            app.Simulate = uibutton(app.StaticTab, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.LogsTextAreaLabel = uilabel(app.StaticTab);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.Logs = uitextarea(app.StaticTab);
            app.Save = uicheckbox(app.StaticTab);
            app.Save.Text = 'Save data';
            app.Save.Value = true;
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.LogsTextAreaLabel_2 = uilabel(app.DynamicTab);
            app.LogsTextAreaLabel_2.Text = 'Logs';
            app.Logs_2 = uitextarea(app.DynamicTab);
            app.EVSimTab = uitab(app.TabGroup);
            app.EVSimTab.Title = 'EVSim';
            app.LogsTextAreaLabel_3 = uilabel(app.EVSimTab);
            app.LogsTextAreaLabel_3.Text = 'Logs';
            app.Logs_3 = uitextarea(app.EVSimTab);
            app.CellSimTab = uitab(app.TabGroup);
            app.CellSimTab.Title = 'CellSim';
            app.LogsTextAreaLabel_4 = uilabel(app.CellSimTab);
            app.LogsTextAreaLabel_4.Text = 'Logs';
            app.Logs_4 = uitextarea(app.CellSimTab);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AbouttheappLabel = uilabel(app.AboutTab);
            app.AbouttheappLabel.Text = 'About the app';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Value = {'The app was built by Ashwin Kumar an Internship student at Kaynes Technologies, the code to proces the data was obtailed from   Plett, Gregory L.''s suppliment to the text book : "Battery  Management Systems, Volume I, Battery Modeling,'};
            app.TextArea_2 = uitextarea(app.AboutTab);
            app.TextArea_2.Value = {'Todo:'; 'repair the datatypes'; 'Fill this doc'; 'add visualise part'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Handler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end